classdef slicer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        SLS                          matlab.ui.control.Slider
        SLSmVLabel                   matlab.ui.control.Label
        ButtonPlot                   matlab.ui.control.Button
        ButtonLoad                   matlab.ui.control.Button
        SliderY                      matlab.ui.control.Slider
        SliderX                      matlab.ui.control.Slider
        Ax3d                         matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        ButtonGroupDomain            matlab.ui.container.ButtonGroup
        ButtonXY                     matlab.ui.control.ToggleButton
        ButtonFA                     matlab.ui.control.ToggleButton
        RespAC                       matlab.ui.control.NumericEditField
        RespACVmWLabel               matlab.ui.control.Label
        RespDC                       matlab.ui.control.NumericEditField
        RespDCVmWLabel               matlab.ui.control.Label
        ProperFreq                   matlab.ui.control.NumericEditField
        ProperfreqMHzEditFieldLabel  matlab.ui.control.Label
        KerrAngle                    matlab.ui.control.NumericEditField
        KerrangleuradLabel           matlab.ui.control.Label
        ProperAmpl                   matlab.ui.control.NumericEditField
        ProperampVppLabel            matlab.ui.control.Label
        ButtonGroupHarm              matlab.ui.container.ButtonGroup
        ButtonK                      matlab.ui.control.ToggleButton
        Button2f                     matlab.ui.control.ToggleButton
        Button1f                     matlab.ui.control.ToggleButton
        ButtonDC                     matlab.ui.control.ToggleButton
        SwitchSlice                  matlab.ui.control.Switch
        Ax2d                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        data        % Data structure
        domain      % Modulation ('FA') or position ('XY') scan
        harm        % Harmonic: 'DC' (default), '1f', or '2f'
        norm        % Normalization factor P0(modulation=0), uW
        shape       % Data shape (m,n)
        index       % Slice coordinates indicies (i,j)
        values      % Slice coordinates values (x,y)
        X           % 2D matrix with X data
        Y           % 2D matrix with Y data
        Z           % 2D matrix with Z data
        x           % 1D array with x-sclice data
        y           % 1D array with y-sclice data
        z           % 1D array with z-sclice data
        % Parameters
        resp_dc     % Photodetector responsivity (DC)
        resp_ac     % Photodetector responsivity (AC)
        splitter    % AC signal attenuation due to splitter
        f0          % Proper modulation frequency
        a0          % Proper modulation amplitude
        theta       % Kerr angle
    end

    methods (Access = private)

        function Z = combine(app, z, shape)
            % Utility function used to convert 1D array into 2D array
            if nargin < 3
                shape = app.shape;
            end
            n = shape(1);
            m = shape(2);
            n0 = n*m;
            if numel(z) ~= n0
                error("[app.combine] Incorrect shape.");
            end
            Z = zeros(n,m);
            for i0 = 0:n0-1
                i = fix(i0/m)+1;
                j = mod(i0,m)+1;
                if ~mod(i,2)
                    j = m-j+1;
                end
                Z(i,j) = z(i0+1);
            end
        end

    end

    methods (Access = public)
        %%% Update function
        function update_index(app, newindex)
            % Sets slice indecies (i,j)
            if nargin < 2
                if isempty(app.X)
                    warning("[app.update_index] Data is absent!");
                    app.index = [1, 1];
                    return;
                end
                Xvalue = app.SliderX.Value;
                Yvalue = app.SliderY.Value;
                [~, Xindex] = min(abs(app.x-Xvalue));
                [~, Yindex] = min(abs(app.y-Yvalue));
            else
                Xindex = newindex(1);
                Yindex = newindex(2);
            end
            app.SliderX.Value = app.x(Xindex);
            app.SliderY.Value = app.y(Yindex);
            app.index = [Xindex, Yindex];
        end

        function update_slice(app)
            % Makes data slice from 2D arrays X,Y,Z into 1D arrays x,y,z
            if isempty(app.X)
                warning("[app.update_slice] Data is absent!");
                return
            end
            i = app.index(2);
            j = app.index(1);
            switch app.SwitchSlice.Value
                case app.SwitchSlice.Items(1)
                    app.z = app.Z(i,:);
                case app.SwitchSlice.Items(2)
                    app.z = app.Z(:,j);
            end
        end

        %%% Data functions
        function load_data(app)
            % Opens datafile, reads and plots data.
            [file, folder] = uigetfile('*.mat', 'Select data file');
            while file == 0
                YN = uiconfirm(app.UIFigure, ...
                    'Do you want to select another file?', ...
                    'Invalid file', 'Options', {'Yes','No'});
                if strcmpi(YN,'No')
                    delete(app);
                    return
                end
                [file, folder] = uigetfile('*.mat', 'Select data file');
            end
            filename = fullfile(folder, file);
            app.data = load(filename);
            % Read datafile
            try
                app.read_data();
            catch
                warning("[app.load_data] Failed to read data structure");
                return
            end
            % Make 3D (surf) plot
            try
                app.plot3d();
            catch
                warning("[app.load_data] Failed to make 3D plot");
                return
            end
            % Make 2D (slice) plot
            try
                app.plot2d();
            catch
                warning("[app.load_data] Failed to make 2D plot");
                return
            end
        end

        function flag = read_data(app)
            % Extracts data from datafile
            flag = 0; % Assuming function executed without errors
            switch app.domain
                case 'FA'
                    Xflat = app.data.logdata.waveform.freq*1e-6;
                    Yflat = app.data.logdata.waveform.ampl;
                    switch app.harm
                        case 'DC'
                            Zflat = abs(app.data.logdata.voltmeter.v1);
                            Zflat = 1e3*Zflat/app.resp_dc;
                        case '1f'
                            Zflat = abs(app.data.logdata.lockin.R);
                            Zflat = 1e3*Zflat/app.resp_ac;
                            if app.SLS.Value
                                Zflat = Zflat/app.splitter;
                            end
                        case '2f'
                            if app.SLS.Value
                                sls = app.SLS.Value;
                                X2 = sls*app.data.logdata.lockin.AUX1;
                                Y2 = sls*app.data.logdata.lockin.AUX2;
                                Zflat = sqrt(X2.^2+Y2.^2);
                                Zflat = Zflat/app.resp_ac;
                                Zflat = Zflat/app.splitter;
                            else
                                Zflat = abs(app.data.logdata.lockin.R);
                                Zflat = 1e3*Zflat/app.resp_ac;
                            end
                        case 'K'
                            if ~isfield(app.data.logdata.lockin, 'AUX1')
                                warning("[app.read_data] Logdata misses lockin.AUX field.")
                                flag = 1;
                                return
                            end
                            if ~app.SLS.Value
                                set(app.SLS, 'Value', app.SLS.MajorTicks(end));
                            end
                            sls = 1e-3*app.SLS.Value;
                            X2 = app.data.logdata.lockin.AUX1;
                            Y2 = app.data.logdata.lockin.AUX2;
                            Zflat = sls*sqrt(X2.^2+Y2.^2);
                            
                            f = Xflat;
                            a = Yflat;
                            phi = 2*0.92*a/app.a0;
                            snf = sin(pi/2*f/app.f0);
                            b1 = besselj(1,phi.*snf);
                            b2 = besselj(2,phi.*snf);
                            c = b2./b1;
                            Z1 = abs(app.data.logdata.lockin.R);
                            Zflat = 1e6*atan(c.*Z1./Zflat);
                    end
                case 'XY'
                    warning("[app.read_data] XY domain is not implemented.");
                    flag = 1;
                    return
            end
            app.norm = 1e3*abs(app.data.logdata.voltmeter.v1(1))/app.resp_dc;
            app.shape = app.data.logdata.sweep.shape;
            n_curr = numel(Xflat);
            n_full = prod(app.shape);
            if n_curr < n_full
                Xflat = app.data.logdata.sweep.range(1,:);
                Yflat = app.data.logdata.sweep.range(2,:);
                if strcmp(app.ButtonGroupDomain.SelectedObject.Text, 'FA')
                    Xflat = Xflat*1e-6;
                end
                Zflat(n_curr:n_full) = Zflat(1);
            end
            app.X = app.combine(Xflat);
            app.Y = app.combine(Yflat);
            app.Z = app.combine(Zflat);
            app.x = app.X(1,:);
            app.y = app.Y(:,1);
            app.update_index();
            app.update_slice();
        end

        %%% Plot functions
        function plot3d(app)
            if isempty(app.X)
                warning("[app.plot3d] Data is absent!");
                return;
            end
            cla(app.Ax3d);
            surf(app.Ax3d, app.X, app.Y, app.Z, 'EdgeAlpha', .1);
            app.setup_slider();
            i = app.index(2);
            j = app.index(1);
            plot3(app.Ax3d, app.X(i,:), app.Y(i,:), app.Z(i,:), 'k');
            plot3(app.Ax3d, app.X(:,j), app.Y(:,j), app.Z(:,j), 'k');
        end

        function plot2d(app)
            if isempty(app.x)
                warning("[app.plot2d] Data is absent!");
                return;
            end
            cla(app.Ax2d);
            c2t = cos(2*app.theta);
            s1t = sin(app.theta);
            c1t = cos(app.theta);
            switch app.SwitchSlice.Value
                case app.SwitchSlice.Items(1)
                    plot(app.Ax2d, app.x, app.z);
                    xlabel(app.Ax2d, app.Ax3d.XLabel.String);
                    % Theoretical values
                    f = app.x;
                    a = app.Y(app.index(2), 1);
                    phi = 2*0.92*a/app.a0;
                    p0 = 1+c2t*besselj(0,phi*sin(pi/2*f/app.f0));
                    p1 = 2*s1t*besselj(1,phi*sin(pi/2*f/app.f0));
                    p2 = 2*c1t*besselj(2,phi*sin(pi/2*f/app.f0));
                    A = app.norm/(1+c2t);
                    switch app.ButtonGroupHarm.SelectedObject.Text
                        case 'DC'
                            plot(app.Ax2d, app.x, A*p0, '--');
                        case '1f'
                            plot(app.Ax2d, app.x, A*abs(p1), '--');
                        case '2f'
                            plot(app.Ax2d, app.x, A*abs(p2), '--');
                        case 'K'
                            k = 1e6*app.theta*ones(size(app.x));
                            plot(app.Ax2d, app.x, k, '--');
                    end

                case app.SwitchSlice.Items(2)
                    plot(app.Ax2d, app.y, app.z);
                    xlabel(app.Ax2d, app.Ax3d.YLabel.String);
                    % Theoretical values
                    f = app.X(1, app.index(1));
                    a = app.y;
                    phi = 2*0.92*a/app.a0;
                    p0 = 1+c2t*besselj(0,phi*sin(pi/2*f/app.f0));
                    p1 = 2*s1t*besselj(1,phi*sin(pi/2*f/app.f0));
                    p2 = 2*c2t*besselj(2,phi*sin(pi/2*f/app.f0));
                    A = app.norm/(1+c2t);
                    switch app.ButtonGroupHarm.SelectedObject.Text
                        case 'DC'
                            plot(app.Ax2d, app.y, A*p0, '--');
                        case '1f'
                            plot(app.Ax2d, app.y, A*abs(p1), '--');
                        case '2f'
                            plot(app.Ax2d, app.y, A*abs(p2), '--');
                        case 'K'
                            k = 1e6*app.theta*ones(size(app.y));
                            plot(app.Ax2d, app.y, k, '--');
                    end
            end
        end

        %%% Setup functions
        function setup_axes(app)
            % 3D axes
            cb = colorbar(app.Ax3d, 'westoutside');
            hold(app.Ax3d, 'on');
            grid(app.Ax3d, 'on');
            %title(app.Ax3d, 'DC power P_0, \muW');
            switch app.ButtonGroupDomain.SelectedObject.Text
                case 'FA'
                    xylabels = ["Frequency, MHz", "Amplitude, Vpp"];
                case 'XY'
                    xylabels = ["Position X, mm", "Position Y, mm"];
            end
            switch app.ButtonGroupHarm.SelectedObject.Text
                case 'DC'
                    zlabel = "DC power P_0, \muW";
                case '1f'
                    zlabel = "1\omega power P_1, \muW";
                case '2f'
                    zlabel = "2\omega power P_2, \muW";
                case 'K'
                    zlabel = "Kerr angle, \murad";
            end
            cb.Label.String = zlabel;
            xlabel(app.Ax3d, xylabels(1));
            ylabel(app.Ax3d, xylabels(2));
            set(app.Ax3d, 'YAxisLocation', 'right');

            % 2D axes
            hold(app.Ax2d, 'on');
            grid(app.Ax2d, 'on');
            ylabel(app.Ax2d, zlabel);
            vals = convertCharsToStrings(app.SwitchSlice.Items);
            xlabel(app.Ax2d, xylabels(vals == app.SwitchSlice.Value));
        end

        function setup_slider(app)
            if isempty(app.X) || isempty(app.shape)
                warning("[app.setup_slider] Data is absent!");
                return;
            end
            app.SliderX.Limits = [min(app.X(1,:)), max(app.X(1,:))];
            app.SliderY.Limits = [min(app.Y(:,1)), max(app.Y(:,1))];
        end

        function setup_parameters(app)
            % Set paramters to their default values
            app.domain = app.ButtonGroupDomain.SelectedObject.Text;
            app.harm = app.ButtonGroupHarm.SelectedObject.Text;
            app.resp_dc = 4.7;   % V/mW {4.7, 10.4}
            app.resp_ac = 5;     % V/mW {18.8, 40.16}
            app.f0 = 4.84;          % MHz
            app.a0 = 0.54;          % Vpp {0.54, 1.10}
            app.theta = 10;         % urad
            app.splitter = .673;    % attenuation
            app.RespDC.set('Value', app.resp_dc);
            app.RespAC.set('Value', app.resp_ac);
            app.ProperFreq.set('Value', app.f0);
            app.ProperAmpl.set('Value', app.a0);
            app.KerrAngle.set('Value', app.theta);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setup_axes();
            app.setup_parameters();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {557, 557};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {806, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ButtonLoad
        function ButtonLoadPushed(app, event)
            app.load_data();
        end

        % Value changed function: SwitchSlice
        function SwitchSliceValueChanged(app, event)
            app.update_slice();
            app.plot3d();
            app.plot2d();
        end

        % Value changed function: SliderX
        function SliderXValueChanged(app, event)
            app.update_index();
            app.update_slice();
            app.plot3d();
            app.plot2d();
        end

        % Value changed function: SliderY
        function SliderYValueChanged(app, event)
            app.update_index();
            app.update_slice();
            app.plot3d();
            app.plot2d();
        end

        % Selection changed function: ButtonGroupDomain
        function ButtonGroupDomainSelectionChanged(app, event)
            app.domain = app.ButtonGroupDomain.SelectedObject.Text;
            if ~isempty(app.X)
                flag = app.read_data();
                if flag
                    app.ButtonGroupDomain.set('SelectedObject', event.OldValue)
                    app.domain = event.OldValue.Text;
                else
                    app.ButtonPlotPushed(event);
                end
            end
        end

        % Selection changed function: ButtonGroupHarm
        function ButtonGroupHarmSelectionChanged(app, event)
            app.harm = app.ButtonGroupHarm.SelectedObject.Text;
            if ~isempty(app.X)
                flag = app.read_data();
                if flag
                    app.ButtonGroupHarm.set('SelectedObject', event.OldValue)
                    app.harm = event.OldValue.Text;
                else
                    app.ButtonPlotPushed(event);
                end
            end
        end

        % Button pushed function: ButtonPlot
        function ButtonPlotPushed(app, event)
            app.setup_axes();
            app.plot3d();
            app.plot2d();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            %disp(key);
            if isempty(app.x), return; end
            switch key
                case {'p', 'numpad0'}
                    app.ButtonPlotPushed(event);
                case {'x', 'h'}
                    app.SwitchSlice.Value = app.SwitchSlice.Items{1};
                    app.SwitchSliceValueChanged();
                case {'y', 'v'}
                    app.SwitchSlice.Value = app.SwitchSlice.Items{2};
                    app.SwitchSliceValueChanged();
                case {'rightarrow', 'numpad6'}
                    if app.index(1) >= numel(app.x), return; end
                    app.index(1) = app.index(1) + 1;
                    app.update_index(app.index);
                case {'leftarrow', 'numpad4'}
                    if app.index(1) <= 1, return; end
                    app.index(1) = app.index(1) - 1;
                    app.update_index(app.index);
                case {'uparrow', 'numpad8'}
                    if app.index(2) >= numel(app.y), return; end
                    app.index(2) = app.index(2) + 1;
                    app.update_index(app.index);
                case {'downarrow', 'numpad2'}
                    if app.index(2) <= 1, return; end
                    app.index(2) = app.index(2) - 1;
                    app.update_index(app.index);
            end
            app.update_slice();
            app.plot3d();
            app.plot2d();
        end

        % Value changed function: ProperAmpl
        function ProperAmplValueChanged(app, event)
            app.a0 = app.ProperAmpl.Value;
            app.plot3d();
            app.plot2d();
        end

        % Value changed function: KerrAngle
        function KerrAngleValueChanged(app, event)
            app.theta = 1e-6*app.KerrAngle.Value;
            app.plot3d();
            app.plot2d();
        end

        % Value changed function: ProperFreq
        function ProperFreqValueChanged(app, event)
            app.f0 = app.ProperFreq.Value;
            app.plot3d();
            app.plot2d();
        end

        % Value changed function: RespDC
        function RespDCValueChanged(app, event)
            app.resp_dc = app.RespDC.Value;
            app.read_data();
            app.plot3d();
            app.plot2d();
        end

        % Value changed function: RespAC
        function RespACValueChanged(app, event)
            app.resp_ac = app.RespAC.Value;
            app.read_data();
            app.plot3d();
            app.plot2d();
        end

        % Value changed function: SLS
        function SLSValueChanged(app, event)
            value = app.SLS.Value;
            [~, idx] = min(abs(value-app.SLS.MajorTicks));
            value = app.SLS.MajorTicks(idx);
            set(app.SLS, 'Value', value);
            app.read_data();
            app.ButtonPlotPushed();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Surface / colormap plot';
            app.Ax3d = uiaxes(app.LeftPanel);
            app.Ax3d.XTick = [];
            app.Ax3d.YTick = [];
            app.SliderX = uislider(app.LeftPanel);
            app.SliderX.ValueChangedFcn = createCallbackFcn(app, @SliderXValueChanged, true);
            app.SliderY = uislider(app.LeftPanel);
            app.SliderY.ValueChangedFcn = createCallbackFcn(app, @SliderYValueChanged, true);
            app.ButtonLoad = uibutton(app.LeftPanel, 'push');
            app.ButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadPushed, true);
            app.ButtonLoad.Text = 'Load';
            app.ButtonPlot = uibutton(app.LeftPanel, 'push');
            app.ButtonPlot.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlotPushed, true);
            app.ButtonPlot.Text = 'Plot';
            app.SLSmVLabel = uilabel(app.LeftPanel);
            app.SLSmVLabel.Text = 'SLS, mV';
            app.SLS = uislider(app.LeftPanel);
            app.SLS.Limits = [0 250];
            app.SLS.MajorTicks = [0 100 250];
            app.SLS.MajorTickLabels = {'0', '100', '250'};
            app.SLS.ValueChangedFcn = createCallbackFcn(app, @SLSValueChanged, true);
            app.SLS.MinorTicks = [0 100 250];
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = '1D slice plot';
            app.Ax2d = uiaxes(app.RightPanel);
            xlabel(app.Ax2d, 'X')
            ylabel(app.Ax2d, 'Y')
            zlabel(app.Ax2d, 'Z')
            app.SwitchSlice = uiswitch(app.RightPanel, 'slider');
            app.SwitchSlice.Items = {'Horizontal', 'Vertical'};
            app.SwitchSlice.ValueChangedFcn = createCallbackFcn(app, @SwitchSliceValueChanged, true);
            app.SwitchSlice.Value = 'Horizontal';
            app.ButtonGroupHarm = uibuttongroup(app.RightPanel);
            app.ButtonGroupHarm.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupHarmSelectionChanged, true);
            app.ButtonDC = uitogglebutton(app.ButtonGroupHarm);
            app.ButtonDC.Text = 'DC';
            app.ButtonDC.Value = true;
            app.Button1f = uitogglebutton(app.ButtonGroupHarm);
            app.Button1f.Text = '1f';
            app.Button2f = uitogglebutton(app.ButtonGroupHarm);
            app.Button2f.Text = '2f';
            app.ButtonK = uitogglebutton(app.ButtonGroupHarm);
            app.ButtonK.Text = 'K';
            app.ProperampVppLabel = uilabel(app.RightPanel);
            app.ProperampVppLabel.Text = 'Proper amp, Vpp';
            app.ProperAmpl = uieditfield(app.RightPanel, 'numeric');
            app.ProperAmpl.Limits = [0 Inf];
            app.ProperAmpl.ValueChangedFcn = createCallbackFcn(app, @ProperAmplValueChanged, true);
            app.ProperAmpl.Value = 1;
            app.KerrangleuradLabel = uilabel(app.RightPanel);
            app.KerrangleuradLabel.Text = 'Kerr angle, urad';
            app.KerrAngle = uieditfield(app.RightPanel, 'numeric');
            app.KerrAngle.ValueChangedFcn = createCallbackFcn(app, @KerrAngleValueChanged, true);
            app.KerrAngle.Value = 1;
            app.ProperfreqMHzEditFieldLabel = uilabel(app.RightPanel);
            app.ProperfreqMHzEditFieldLabel.Text = 'Proper freq, MHz';
            app.ProperFreq = uieditfield(app.RightPanel, 'numeric');
            app.ProperFreq.Limits = [0 Inf];
            app.ProperFreq.ValueChangedFcn = createCallbackFcn(app, @ProperFreqValueChanged, true);
            app.ProperFreq.Value = 4.84;
            app.RespDCVmWLabel = uilabel(app.RightPanel);
            app.RespDCVmWLabel.Text = 'Resp DC, V/mW';
            app.RespDC = uieditfield(app.RightPanel, 'numeric');
            app.RespDC.Limits = [0 Inf];
            app.RespDC.ValueChangedFcn = createCallbackFcn(app, @RespDCValueChanged, true);
            app.RespDC.Value = 1;
            app.RespACVmWLabel = uilabel(app.RightPanel);
            app.RespACVmWLabel.Text = 'Resp AC, V/mW';
            app.RespAC = uieditfield(app.RightPanel, 'numeric');
            app.RespAC.Limits = [0 Inf];
            app.RespAC.ValueChangedFcn = createCallbackFcn(app, @RespACValueChanged, true);
            app.RespAC.Value = 1;
            app.ButtonGroupDomain = uibuttongroup(app.RightPanel);
            app.ButtonGroupDomain.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupDomainSelectionChanged, true);
            app.ButtonFA = uitogglebutton(app.ButtonGroupDomain);
            app.ButtonFA.Text = 'FA';
            app.ButtonFA.Value = true;
            app.ButtonXY = uitogglebutton(app.ButtonGroupDomain);
            app.ButtonXY.Text = 'XY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slicer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end