classdef magneton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProgrammedFieldEditFieldLabel  matlab.ui.control.Label
        ProgrammedFieldEditField       matlab.ui.control.NumericEditField
        RampRateEditFieldLabel         matlab.ui.control.Label
        RampRateEditField              matlab.ui.control.NumericEditField
        State                          matlab.ui.control.Label
        RampButton                     matlab.ui.control.StateButton
        SwitchHeaterButton             matlab.ui.control.StateButton
        FieldUnitsSwitch               matlab.ui.control.Switch
        LocalSwitch                    matlab.ui.control.ToggleSwitch
        Switch                         matlab.ui.control.Switch
    end

    
    properties (Access = private)
        magnet = Drivers.AMI420;    % Instrument class
        
        timer = timer;              % MATLAB timer
        timeStep = 1;              % Timer period
    end
    
    methods (Access = private)
        
        function updateState(app)
        %   Update state string and object's fields
            app.magnet.update();
            
            app.State.Text = sprintf(['State:', '\n', ...
                app.magnet.state, '\n\n', ...
                ' Field:  ', num2str(app.magnet.field, '%.5f'), ' ', ...
                app.magnet.fieldUnits, '\n', ...
                'Current: ', num2str(app.magnet.current, '%.4f'), ' A\n', ...
                'Voltage: ', num2str(app.magnet.voltage, '%.2f'), ' V\n', ...
                ' Supply: ', num2str(app.magnet.voltageSupply, '%.2f'), ' V\n' ...
                ]);
            
            app.ProgrammedFieldEditField.set('Value', ...
                app.magnet.programmedField);
            app.RampRateEditField.set('Value', app.magnet.rampRateField);
            
            drawnow;
        end
        
        function updateButtons(app)
        %   Check that 'ramp/pause', 'pswitch heater', 'fieldUnits' buttons
        %   are in the right state
            app.magnet.update();
            
            if strcmp(app.magnet.state, 'ramping')
                app.RampButton.set('Value', 1);
                app.RampButton.Text = 'Pause';
            end
            if strcmp(app.magnet.state, 'paused')
                app.RampButton.set('Value', 0);
                app.RampButton.Text = 'Ramp';
            end
            
            if app.magnet.pswitchState
                app.SwitchHeaterButton.set('Value', 1);
            else
                app.SwitchHeaterButton.set('Value', 0);
            end
            
            if all(app.magnet.fieldUnits == 'T')
                app.FieldUnitsSwitch.set('Value', 'T');
            else
                app.FieldUnitsSwitch.set('Value', 'kG');
            end
            
            drawnow;
        end
        
        function start_fcn(app, thisEvent)
        %   Timer start function
            app.updateState();
            app.updateButtons();
            
            date = datestr(thisEvent.Data.time, 'yyyy-mm-dd HH:MM:SS');
            disp(['Timer started: ', date]);
        end
        
        function timer_fcn(app, thisEvent)
        %   Timer action function
            app.magnet.update();
            
            app.updateState();
            app.updateButtons();
            
            if nargin > 1
                date = datestr(thisEvent.Data.time, 'yyyy-mm-dd HH:MM:SS');
                %disp(['Timer action: ', date]);
            end
            drawnow;
        end
        
        function stop_fcn(app, thisEvent)
        %   Timer stop function
            app.State.Text = 'Restart the timer.';
            date = datestr(thisEvent.Data.time, 'yyyy-mm-dd HH:MM:SS');
            disp(['Timer finished: ', date]);
        end

    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, gpib)
            if nargin < 2
                gpib = 22;
            end
            app.magnet.load(gpib);
            
            app.updateState();
            app.updateButtons();
            
            app.timer.StartFcn = @(~, thisEvent)app.start_fcn(thisEvent);
            app.timer.TimerFcn = @(~, thisEvent)app.timer_fcn(thisEvent);
            app.timer.StopFcn = @(~, thisEvent)app.stop_fcn(thisEvent);
            
            app.timer.Period = app.timeStep;
            app.timer.TasksToExecute = 1e7;
            app.timer.ExecutionMode = 'fixedRate';
            
            start(app.timer);
        end

        % Value changed function: RampButton
        function RampButtonValueChanged(app, event)
            value = app.RampButton.Value;
            app.magnet.ramp();
            app.timer_fcn();
            if value
                app.RampButton.Text = 'Pause';
            else
                app.RampButton.Text = 'Ramp';
            end
        end

        % Value changed function: LocalSwitch
        function LocalSwitchValueChanged(app, event)
            app.magnet.local();
            app.updateState();
            app.updateButtons();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.magnet.remote
                app.magnet.local();
            end
            stop(app.timer);
            delete(app)
        end

        % Value changed function: SwitchHeaterButton
        function SwitchHeaterButtonValueChanged(app, event)
            value = app.SwitchHeaterButton.Value;
            app.magnet.pswitch();
            app.timer_fcn();
        end

        % Value changed function: FieldUnitsSwitch
        function FieldUnitsSwitchValueChanged(app, event)
            value = app.FieldUnitsSwitch.Value;
            app.magnet.set('fieldUnits', value);
            app.timer_fcn();
        end

        % Value changed function: ProgrammedFieldEditField
        function ProgrammedFieldEditFieldValueChanged(app, event)
            value = app.ProgrammedFieldEditField.Value;
            if ((abs(value) < 1e-5) || (abs(value) >= 0.5)) && (value ~= 0.0)
                disp('Value is out of range.');
                app.ProgrammedFieldEditField.set('Value', ...
                    app.magnet.programmedField);
                return
            end
            app.magnet.set('programmedField', value);
        end

        % Value changed function: RampRateEditField
        function RampRateEditFieldValueChanged(app, event)
            value = app.RampRateEditField.Value;
            app.magnet.set('rampRate', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProgrammedFieldEditFieldLabel = uilabel(app.UIFigure);
            app.ProgrammedFieldEditFieldLabel.Text = 'Programmed Field';
            app.ProgrammedFieldEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProgrammedFieldEditField.ValueChangedFcn = createCallbackFcn(app, @ProgrammedFieldEditFieldValueChanged, true);
            app.RampRateEditFieldLabel = uilabel(app.UIFigure);
            app.RampRateEditFieldLabel.Text = 'Ramp Rate';
            app.RampRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.RampRateEditField.ValueChangedFcn = createCallbackFcn(app, @RampRateEditFieldValueChanged, true);
            app.State = uilabel(app.UIFigure);
            app.State.Text = {'Status: '; 'Paused'; ''; 'Field: 0 T'; 'Current: 0 A'; 'Voltage: 0 V'; 'Supply: 0 V'; ''};
            app.RampButton = uibutton(app.UIFigure, 'state');
            app.RampButton.ValueChangedFcn = createCallbackFcn(app, @RampButtonValueChanged, true);
            app.RampButton.Text = 'Ramp';
            app.SwitchHeaterButton = uibutton(app.UIFigure, 'state');
            app.SwitchHeaterButton.ValueChangedFcn = createCallbackFcn(app, @SwitchHeaterButtonValueChanged, true);
            app.SwitchHeaterButton.Text = 'Switch Heater';
            app.FieldUnitsSwitch = uiswitch(app.UIFigure, 'slider');
            app.FieldUnitsSwitch.Items = {'T', 'kG'};
            app.FieldUnitsSwitch.ValueChangedFcn = createCallbackFcn(app, @FieldUnitsSwitchValueChanged, true);
            app.FieldUnitsSwitch.Value = 'T';
            app.LocalSwitch = uiswitch(app.UIFigure, 'toggle');
            app.LocalSwitch.Items = {'Remote', 'Local'};
            app.LocalSwitch.ValueChangedFcn = createCallbackFcn(app, @LocalSwitchValueChanged, true);
            app.LocalSwitch.Value = 'Remote';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'T/s', 'T/min'};
            app.Switch.Value = 'T/min';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = magneton(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end