classdef phase_rotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PathtodataEditField  matlab.ui.control.EditField
        SelectdataButton     matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        PhaseSliderLabel     matlab.ui.control.Label
        PhaseSlider          matlab.ui.control.Slider
    end

    
    properties (Access = private)
        logdata % Logdata array
        re
        im
        t
    end
    
    methods (Access = private)
        function set_phase(app)
            data = app.logdata;
            phase = deg2rad(app.PhaseSlider.Value);
            fieldNames = Sagnatron.determine_field_names(data);
            
            app.t = data.(fieldNames.temperature);
            
            re0 = data.(fieldNames.transportX);
            im0 = data.(fieldNames.transportY);
            z0 = (re0+im0*1i)*exp(1i*phase);
            app.re = real(z0); 
            app.im = imag(z0);
        end
        
        function plot_data(app)
            yyaxis(app.UIAxes, 'left');
            plot(app.UIAxes, app.t, app.re);
            %hold(app.UIAxes, 'on');
            %plot(app.UIAxes, app.t, app.im);
            %hold(app.UIAxes, 'off');
            yyaxis(app.UIAxes, 'right');
            plot(app.UIAxes, app.t, app.im);
            yyaxis(app.UIAxes, 'left');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ylabel(app.UIAxes, 'Transport X, arb.units');
            yyaxis(app.UIAxes, 'right');
            ylabel(app.UIAxes, 'Transport Y, arb.units');
            yyaxis(app.UIAxes, 'left');
        end

        % Button pushed function: SelectdataButton
        function SelectdataButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            [file, folder] = uigetfile('*.mat', 'Select data file');
            app.UIFigure.Visible = 'on';
            if file == 0
                return;
            end
            filepath = fullfile(folder, file);
            
            app.logdata = load(filepath).logdata;
            app.PathtodataEditField.set('Value', filepath);
            
            app.set_phase();
            app.plot_data();
        end

        % Value changed function: PhaseSlider
        function PhaseSliderValueChanged(app, event)
            value = app.PhaseSlider.Value;
            app.set_phase();
            app.plot_data();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PathtodataEditField = uieditfield(app.UIFigure, 'text');
            app.SelectdataButton = uibutton(app.UIFigure, 'push');
            app.SelectdataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectdataButtonPushed, true);
            app.SelectdataButton.Text = 'Select data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Transport')
            xlabel(app.UIAxes, 'Temperature, K')
            ylabel(app.UIAxes, 'Transport X, arb. units')
            app.PhaseSliderLabel = uilabel(app.UIFigure);
            app.PhaseSliderLabel.Text = 'Phase';
            app.PhaseSlider = uislider(app.UIFigure);
            app.PhaseSlider.Limits = [-90 90];
            app.PhaseSlider.MajorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.PhaseSlider.ValueChangedFcn = createCallbackFcn(app, @PhaseSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phase_rotator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end