classdef Recorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StartButton              matlab.ui.control.StateButton
        ChooseDataDropDownLabel  matlab.ui.control.Label
        ChooseDataDropDown       matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
        RecordSwitchLabel        matlab.ui.control.Label
        RecordSwitch             matlab.ui.control.Switch
        SR844Lamp                matlab.ui.control.Lamp
        SR844CheckBox            matlab.ui.control.CheckBox
        InstrumentsLabel         matlab.ui.control.Label
        LS331Lamp                matlab.ui.control.Lamp
        LS331CheckBox            matlab.ui.control.CheckBox
        LS340Lamp                matlab.ui.control.Lamp
        LS340CheckBox            matlab.ui.control.CheckBox
        SR830Lamp                matlab.ui.control.Lamp
        SR830CheckBox            matlab.ui.control.CheckBox
        AMI420Lamp               matlab.ui.control.Lamp
        AMI420CheckBox           matlab.ui.control.CheckBox
        ReconnectButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        instrAll  % all available instruments
        instrRec  % instruments that should be recorded
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.instrAll = Drivers.find_instruments();
            instrNames = fieldnames(app.instrAll);
            for inst = instrNames
                
            end
            app.instrRec = app.instrAll;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.Text = 'Start';
            app.ChooseDataDropDownLabel = uilabel(app.UIFigure);
            app.ChooseDataDropDownLabel.Text = 'Choose Data';
            app.ChooseDataDropDown = uidropdown(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.RecordSwitchLabel = uilabel(app.UIFigure);
            app.RecordSwitchLabel.Text = 'Record';
            app.RecordSwitch = uiswitch(app.UIFigure, 'slider');
            app.SR844Lamp = uilamp(app.UIFigure);
            app.SR844CheckBox = uicheckbox(app.UIFigure);
            app.SR844CheckBox.Text = 'SR844';
            app.InstrumentsLabel = uilabel(app.UIFigure);
            app.InstrumentsLabel.Text = 'Instruments';
            app.LS331Lamp = uilamp(app.UIFigure);
            app.LS331CheckBox = uicheckbox(app.UIFigure);
            app.LS331CheckBox.Text = 'LS331';
            app.LS340Lamp = uilamp(app.UIFigure);
            app.LS340CheckBox = uicheckbox(app.UIFigure);
            app.LS340CheckBox.Text = 'LS340';
            app.SR830Lamp = uilamp(app.UIFigure);
            app.SR830CheckBox = uicheckbox(app.UIFigure);
            app.SR830CheckBox.Text = 'SR830';
            app.AMI420Lamp = uilamp(app.UIFigure);
            app.AMI420CheckBox = uicheckbox(app.UIFigure);
            app.AMI420CheckBox.Text = 'AMI420';
            app.ReconnectButton = uibutton(app.UIFigure, 'push');
            app.ReconnectButton.Text = 'Reconnect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Recorder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end