classdef analyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        selectDataButton              matlab.ui.control.Button
        fileNameField                 matlab.ui.control.EditField
        plotButton                    matlab.ui.control.Button
        phaseSlider                   matlab.ui.control.Slider
        autoPhaseButton               matlab.ui.control.StateButton
        UIAxes                        matlab.ui.control.UIAxes
        tempRangeLabel                matlab.ui.control.Label
        TminEditFieldLabel            matlab.ui.control.Label
        tempRangeMinField             matlab.ui.control.NumericEditField
        TmaxEditFieldLabel            matlab.ui.control.Label
        tempRangeMaxField             matlab.ui.control.NumericEditField
        tempOffsetCheckBox            matlab.ui.control.CheckBox
        tempOffsetLabel               matlab.ui.control.Label
        TminEditField_2Label          matlab.ui.control.Label
        tempOffsetMinField            matlab.ui.control.NumericEditField
        TmaxEditField_2Label          matlab.ui.control.Label
        tempOffsetMaxField            matlab.ui.control.NumericEditField
        TemperaturebinEditFieldLabel  matlab.ui.control.Label
        tempBinField                  matlab.ui.control.NumericEditField
        saveDataButton                matlab.ui.control.Button
        BrowseRawDataLabel            matlab.ui.control.Label
        timeButton                    matlab.ui.control.Button
        tempButton                    matlab.ui.control.Button
    end

    % User-defined properties
    properties (Access = private)
        analyzer = Sagnatron.Analyser();    % Kerr angle analyser object
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tempBinField.set('Value', app.analyzer.tempBin);
            
            range = app.analyzer.tempRange;
            app.tempRangeMinField.set('Value', range(1));
            app.tempRangeMaxField.set('Value', range(2));
            
            range = app.analyzer.offsetRange;
            app.tempOffsetMinField.set('Value', range(1));
            app.tempOffsetMaxField.set('Value', range(2));
            
            app.tempOffsetCheckBox.set('Value', app.analyzer.showOffset);
            app.autoPhaseButton.set('Value', app.analyzer.autoPhase);
        end

        % Button pushed function: selectDataButton
        function selectDataButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            [file, folder] = uigetfile('*.mat', 'Select data file');
            app.UIFigure.Visible = 'on';
            if file == 0
                return;
            end
            filepath = fullfile(folder, file);
            
            app.analyzer.load(filepath);
            
            app.fileNameField.set('Value', file);
            app.startupFcn();
            
            fprintf("~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~\n");
            fprintf("Loaded %s\n\n", file);
            disp(app.analyzer.comments);
            fprintf("\n");
        end

        % Button pushed function: saveDataButton
        function saveDataButtonPushed(app, event)
            app.analyzer.save();
        end

        % Value changed function: phaseSlider
        function phaseSliderValueChanged(app, event)
            value = app.phaseSlider.Value;
            app.analyzer.phase = deg2rad(value);
            
            app.analyzer.autoPhase = false;
            app.autoPhaseButton.set('Value', false);
            
            app.analyzer.kerr();
        end

        % Value changed function: tempBinField
        function tempBinFieldValueChanged(app, event)
            value = app.tempBinField.Value;
            app.analyzer.tempBin = value;
        end

        % Value changed function: tempRangeMinField
        function tempRangeMinFieldValueChanged(app, event)
            value = app.tempRangeMinField.Value;
            app.analyzer.tempRange(1) = value;
        end

        % Value changed function: tempRangeMaxField
        function tempRangeMaxFieldValueChanged(app, event)
            value = app.tempRangeMaxField.Value;
            app.analyzer.tempRange(2) = value;
        end

        % Value changed function: tempOffsetMinField
        function tempOffsetMinFieldValueChanged(app, event)
            value = app.tempOffsetMinField.Value;
            app.analyzer.offsetRange(1) = value;
            if value >= app.analyzer.offsetRange(2)
                value_max = value + 2*app.analyzer.tempBin;
                app.analyzer.offsetRange(2) = value_max;
                app.tempOffsetMaxField.set('Value', value_max);
            end
            app.autoPhaseButton.set('Value', true);
        end

        % Value changed function: tempOffsetMaxField
        function tempOffsetMaxFieldValueChanged(app, event)
            value = app.tempOffsetMaxField.Value;
            app.analyzer.offsetRange(2) = value;
            if value <= app.analyzer.offsetRange(1)
                value_min = value - 2*app.analyzer.tempBin;
                app.analyzer.offsetRange(1) = value_min;
                app.tempOffsetMinField.set('Value', value_min);
            end
            app.autoPhaseButton.set('Value', true);
        end

        % Value changed function: tempOffsetCheckBox
        function tempOffsetCheckBoxValueChanged(app, event)
            value = app.tempOffsetCheckBox.Value;
            app.analyzer.showOffset = value;
        end

        % Value changed function: autoPhaseButton
        function autoPhaseButtonValueChanged(app, event)
            value = app.autoPhaseButton.Value;
            app.analyzer.autoPhase = value;
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            if isempty(app.analyzer.data)
                app.selectDataButtonPushed();
            end
            
            app.analyzer.kerrAxes = app.UIAxes;
            app.analyzer.autoPhase = app.autoPhaseButton.Value;
            
            app.analyzer.kerr();
            
            phase = app.analyzer.phase;
            app.phaseSlider.set('Value', rad2deg(phase));
            
            app.UIFigure.Visible = 'on';
            
            app.autoPhaseButton.set('Value', false);
            app.analyzer.autoPhase = app.autoPhaseButton.Value;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key, "d")
                app.selectDataButtonPushed();
            end
            if strcmp(key, "s")
                app.saveDataButtonPushed();
            end
            if strcmp(key, "p")
                app.plotButtonPushed();
            end
            if strcmp(key, "t")
                app.tempButtonPushed();
            end
            if strcmp(key, "o")
                value = app.tempOffsetCheckBox.Value;
                app.tempOffsetCheckBox.set('Value', ~value);
                app.tempOffsetCheckBoxValueChanged();
            end
            if strcmp(key, "a")
                value = app.autoPhaseButton.Value;
                app.autoPhaseButton.set('Value', ~value);
                app.autoPhaseButtonValueChanged();
            end
            
            step = .5;
            if strcmp(key, "rightarrow")
                value = app.phaseSlider.Value;
                newValue = value+step;
                app.phaseSlider.set('Value', newValue);
                app.phaseSliderValueChanged();
            end
            if strcmp(key, "leftarrow")
                value = app.phaseSlider.Value;
                newValue = value-step;
                app.phaseSlider.set('Value', newValue);
                app.phaseSliderValueChanged();
            end
            
            step = .02;
            if strcmp(key, "uparrow")
                value = app.tempBinField.Value;
                newValue = value+step;
                app.tempBinField.set('Value', newValue);
                app.analyzer.tempBin = newValue;
                app.plotButtonPushed();
            end
            if strcmp(key, "downarrow")
                value = app.tempBinField.Value;
                if value < 2*step
                    return;
                end
                newValue = value-step;
                app.tempBinField.set('Value', newValue);
                app.analyzer.tempBin = newValue;
                app.plotButtonPushed();
            end
        end

        % Button pushed function: timeButton
        function timeButtonPushed(app, event)
            app.analyzer.plott();
        end

        % Button pushed function: tempButton
        function tempButtonPushed(app, event)
            app.analyzer.plot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.selectDataButton = uibutton(app.UIFigure, 'push');
            app.selectDataButton.ButtonPushedFcn = createCallbackFcn(app, @selectDataButtonPushed, true);
            app.selectDataButton.Text = 'Select data';
            app.fileNameField = uieditfield(app.UIFigure, 'text');
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'Plot';
            app.phaseSlider = uislider(app.UIFigure);
            app.phaseSlider.Limits = [-90 90];
            app.phaseSlider.ValueChangedFcn = createCallbackFcn(app, @phaseSliderValueChanged, true);
            app.autoPhaseButton = uibutton(app.UIFigure, 'state');
            app.autoPhaseButton.ValueChangedFcn = createCallbackFcn(app, @autoPhaseButtonValueChanged, true);
            app.autoPhaseButton.Text = {'Auto'; 'phase'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Kerr Angle')
            xlabel(app.UIAxes, 'Temperature T, K')
            ylabel(app.UIAxes, 'Kerr angle \theta_K, \mu{rad}')
            app.tempRangeLabel = uilabel(app.UIFigure);
            app.tempRangeLabel.Text = 'Temperature range';
            app.TminEditFieldLabel = uilabel(app.UIFigure);
            app.TminEditFieldLabel.Text = 'T min';
            app.tempRangeMinField = uieditfield(app.UIFigure, 'numeric');
            app.tempRangeMinField.ValueChangedFcn = createCallbackFcn(app, @tempRangeMinFieldValueChanged, true);
            app.tempRangeMinField.Value = -Inf;
            app.TmaxEditFieldLabel = uilabel(app.UIFigure);
            app.TmaxEditFieldLabel.Text = 'T max';
            app.tempRangeMaxField = uieditfield(app.UIFigure, 'numeric');
            app.tempRangeMaxField.ValueChangedFcn = createCallbackFcn(app, @tempRangeMaxFieldValueChanged, true);
            app.tempRangeMaxField.Value = Inf;
            app.tempOffsetCheckBox = uicheckbox(app.UIFigure);
            app.tempOffsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @tempOffsetCheckBoxValueChanged, true);
            app.tempOffsetCheckBox.Text = 'Show offset range';
            app.tempOffsetLabel = uilabel(app.UIFigure);
            app.tempOffsetLabel.Text = 'Offset range';
            app.TminEditField_2Label = uilabel(app.UIFigure);
            app.TminEditField_2Label.Text = 'T min';
            app.tempOffsetMinField = uieditfield(app.UIFigure, 'numeric');
            app.tempOffsetMinField.ValueChangedFcn = createCallbackFcn(app, @tempOffsetMinFieldValueChanged, true);
            app.TmaxEditField_2Label = uilabel(app.UIFigure);
            app.TmaxEditField_2Label.Text = 'T max';
            app.tempOffsetMaxField = uieditfield(app.UIFigure, 'numeric');
            app.tempOffsetMaxField.ValueChangedFcn = createCallbackFcn(app, @tempOffsetMaxFieldValueChanged, true);
            app.TemperaturebinEditFieldLabel = uilabel(app.UIFigure);
            app.TemperaturebinEditFieldLabel.Text = 'Temperature bin';
            app.tempBinField = uieditfield(app.UIFigure, 'numeric');
            app.tempBinField.ValueChangedFcn = createCallbackFcn(app, @tempBinFieldValueChanged, true);
            app.tempBinField.Value = 0.1;
            app.saveDataButton = uibutton(app.UIFigure, 'push');
            app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButtonPushed, true);
            app.saveDataButton.Text = 'Save data';
            app.BrowseRawDataLabel = uilabel(app.UIFigure);
            app.BrowseRawDataLabel.Text = 'Browse raw data';
            app.timeButton = uibutton(app.UIFigure, 'push');
            app.timeButton.ButtonPushedFcn = createCallbackFcn(app, @timeButtonPushed, true);
            app.timeButton.Text = 'time';
            app.tempButton = uibutton(app.UIFigure, 'push');
            app.tempButton.ButtonPushedFcn = createCallbackFcn(app, @tempButtonPushed, true);
            app.tempButton.Text = 'temp';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end